classdef flame_solver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        ExampleMenu                matlab.ui.container.Menu
        LoadexampleMenu            matlab.ui.container.Menu
        ExampledocumentationMenu   matlab.ui.container.Menu
        GeneratereportMenu         matlab.ui.container.Menu
        ClearMenu                  matlab.ui.container.Menu
        ProbeMenu                  matlab.ui.container.Menu
        ProbedashboardMenu         matlab.ui.container.Menu
        ProjectreportMenu          matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        HelpMenu_3                 matlab.ui.container.Menu
        AboutMenu                  matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        FuelTab                    matlab.ui.container.Tab
        FuelcomponentsPanel        matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        volEditField_6             matlab.ui.control.NumericEditField
        volLabel_6                 matlab.ui.control.Label
        volEditField_5             matlab.ui.control.NumericEditField
        volLabel_5                 matlab.ui.control.Label
        volEditField_4             matlab.ui.control.NumericEditField
        volLabel_4                 matlab.ui.control.Label
        volEditField_3             matlab.ui.control.NumericEditField
        volLabel_3                 matlab.ui.control.Label
        volEditField_2             matlab.ui.control.NumericEditField
        volLabel_2                 matlab.ui.control.Label
        volEditField               matlab.ui.control.NumericEditField
        volLabel                   matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label_1                    matlab.ui.control.Label
        DropDown_6                 matlab.ui.control.DropDown
        DropDown_5                 matlab.ui.control.DropDown
        DropDown_4                 matlab.ui.control.DropDown
        DropDown_3                 matlab.ui.control.DropDown
        DropDown_2                 matlab.ui.control.DropDown
        DropDown                   matlab.ui.control.DropDown
        Button_minus               matlab.ui.control.Button
        Button_plus                matlab.ui.control.Button
        AirTab                     matlab.ui.container.Tab
        AirpropertiesPanel         matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        kg_H_2Okg_airLabel         matlab.ui.control.Label
        SpecifichumidityLabel      matlab.ui.control.Label
        EditField_Spec_Humidity    matlab.ui.control.NumericEditField
        SpinnerDryBulbTemperature  matlab.ui.control.Spinner
        SpinnerRelativeHumidity    matlab.ui.control.Spinner
        SpinnerAtmosfericPressure  matlab.ui.control.Spinner
        CLabel                     matlab.ui.control.Label
        DrybulbtemperatureLabel    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        RelativehumidityLabel      matlab.ui.control.Label
        kPaLabel                   matlab.ui.control.Label
        AtmosfericpressureLabel    matlab.ui.control.Label
        CombustionairPanel         matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        EditField_Air_H2O_Final    matlab.ui.control.NumericEditField
        EditField_Dry_Air_Final    matlab.ui.control.NumericEditField
        EditField_O2_Final         matlab.ui.control.NumericEditField
        kgkg_fuelLabel_9           matlab.ui.control.Label
        H_2OLabel_2                matlab.ui.control.Label
        kgkg_fuelLabel_4           matlab.ui.control.Label
        DryairLabel_2              matlab.ui.control.Label
        kgkg_fuelLabel_3           matlab.ui.control.Label
        O_2Label_2                 matlab.ui.control.Label
        StoichiometricairPanel     matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        EditField_Dry_Air          matlab.ui.control.EditField
        kgkg_fuelLabel_2           matlab.ui.control.Label
        DryairLabel                matlab.ui.control.Label
        EditField_O2               matlab.ui.control.EditField
        kgkg_fuelLabel             matlab.ui.control.Label
        O_2Label                   matlab.ui.control.Label
        ExcessairPanel             matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        CheckBoxHumidAir           matlab.ui.control.CheckBox
        IncludeairhumidityinthestudyLabel  matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        EditFieldExcessAir         matlab.ui.control.NumericEditField
        CombustionanalysisTab      matlab.ui.container.Tab
        GridLayout9                matlab.ui.container.GridLayout
        CombustionproductsvsExcessofairPanel  matlab.ui.container.Panel
        ExcessofAirSlider          matlab.ui.control.RangeSlider
        UIAxes_CB                  matlab.ui.control.UIAxes
        CombustionproductsPanel    matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        EditField_CB_O2            matlab.ui.control.NumericEditField
        EditField_CB_N2            matlab.ui.control.NumericEditField
        EditField_CB_H2O           matlab.ui.control.NumericEditField
        EditField_CB_CO2           matlab.ui.control.NumericEditField
        kgkg_fuelLabel_8           matlab.ui.control.Label
        O_2Label_3                 matlab.ui.control.Label
        kgkg_fuelLabel_7           matlab.ui.control.Label
        N_2Label                   matlab.ui.control.Label
        kgkg_fuelLabel_6           matlab.ui.control.Label
        H_2OLabel                  matlab.ui.control.Label
        kgkg_fuelLabel_5           matlab.ui.control.Label
        CO_2Label                  matlab.ui.control.Label
        FlametemperatureTab        matlab.ui.container.Tab
        GridLayout8                matlab.ui.container.GridLayout
        CombustionproductstemperaturevsExcessofairPanel  matlab.ui.container.Panel
        SliderT_p                  matlab.ui.control.RangeSlider
        UIAxesT_p                  matlab.ui.control.UIAxes
        CombustionproductstemperaturePanel  matlab.ui.container.Panel
        GridLayout10               matlab.ui.container.GridLayout
        T_pLabel                   matlab.ui.control.Label
        CLabel_2                   matlab.ui.control.Label
        EditFieldT_p               matlab.ui.control.NumericEditField
        EnergyanalisysPanel        matlab.ui.container.Panel
        GridLayout5_2              matlab.ui.container.GridLayout
        kJkmol_fuelLabel_4         matlab.ui.control.Label
        kJkmol_fuelLabel_3         matlab.ui.control.Label
        kJkmol_fuelLabel_2         matlab.ui.control.Label
        EditFieldWout              matlab.ui.control.NumericEditField
        EditFieldQout              matlab.ui.control.NumericEditField
        EditFieldWin               matlab.ui.control.NumericEditField
        EditFieldQin               matlab.ui.control.NumericEditField
        W_outLabel                 matlab.ui.control.Label
        Q_outLabel                 matlab.ui.control.Label
        W_inLabel                  matlab.ui.control.Label
        Q_inLabel                  matlab.ui.control.Label
        kJkmol_fuelLabel           matlab.ui.control.Label
        OstwaldTriangleTab         matlab.ui.container.Tab
        GridLayout11               matlab.ui.container.GridLayout
        OstwaldTrianglePanel       matlab.ui.container.Panel
        UIAxesOstwald              matlab.ui.control.UIAxes
        CombustionproductsfromprobePanel  matlab.ui.container.Panel
        GridLayout5_3              matlab.ui.container.GridLayout
        ClearButton                matlab.ui.control.Button
        PlotButton                 matlab.ui.control.Button
        TemperatureLabel           matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        ExcessofairLabel           matlab.ui.control.Label
        vol_CO_2Label              matlab.ui.control.Label
        vol_COLabel                matlab.ui.control.Label
        CLabel_3                   matlab.ui.control.Label
        EditFieldOstT              matlab.ui.control.NumericEditField
        EditFieldOstE              matlab.ui.control.NumericEditField
        EditFieldOstCO2            matlab.ui.control.NumericEditField
        EditFieldOstCO             matlab.ui.control.NumericEditField
        EditFieldOstO2             matlab.ui.control.NumericEditField
        vol_O_2Label               matlab.ui.control.Label
    end

 
    properties (Access = private)                                           % Definition of private properties.

        FuelComponents ...                                                  % Keeps references to the graphic elements of tab fuels.
            = struct('DropDown',{}, ...                                         
            'volEditField',{}, ...
            'volLabel',{}, ...
            'formulaLabel',{});
        NumFuel = 1;                                                        % Number of active fuels in the fuels tab.
        FuelsTable table                                                    % Stores fuel data from the fuels.xlsx file.
        CollectedFuelData                                                   % Compiles the data for the fuels selected in the fuel tab.
    end

    methods (Access = private)                                              % Definition of private methods
                                                                            % Controls the number of active dropdowns according to the
        function updateFuelFields(app)                                      % buttons "+" e "-". Function declaration.
            app.NumFuel = max(1, min(app.NumFuel, ...                       % Sets the number of active dropdowns between 1 and the number of
                numel(app.FuelComponents)));                                % suffixes _n (6 in total)
            for i = 1: numel(app.FuelComponents)                            % For cycle runs through all the positions in the
                visible = i <=app.NumFuel;                                  % FuelComponents structure from 1 to 6. 
                app.FuelComponents(i).DropDown.Visible = visible;           % Visibility true if i is less than the number of fuels
                app.FuelComponents(i).volEditField.Visible = visible;       % or false if it is larger, i.e. it hides it.
                app.FuelComponents(i).volLabel.Visible = visible;           % Activates dropdowns up to the number of active dropdowns
                app.FuelComponents(i).formulaLabel.Visible = visible;       % editfields, volume and chemical formula.
            end                                                             % Controlo da visibilidade dos botões "+" e "-"
            app.Button_minus.Enable = app.NumFuel > 1;                      % Enables the "-" button if more than one fuel is active
            app.Button_plus.Enable = app.NumFuel < ...                      % Enables the "+" button if less than six fuels are active
                numel(app.FuelComponents);                                  % 'on' = 1 and 'off'=0.
        end

        function fuelValueChanged(app, event)                               % Sum validation function of the volumetric % editfields
            for i = 1:app.NumFuel                                           
                app.FuelComponents(i).volEditField.BackgroundColor = ...    % Defining the background colour of active volumetric % editfields
                    'white';                                                % as white.
            end
            vals = zeros(1, app.NumFuel);                                   % Creates the vector vals (with zeros) of dimension equal 
            for i = 1:app.NumFuel                                           % to the number of active fuels.
                vals(i) = app.FuelComponents(i).volEditField.Value;         % Writes the value of each active fuel %vol in the array.
            end
            total = sum(vals);                                              % Adds up the percentages of all active fuels, which must be 100%.
            if total > 100                                                  % Check if the total is more than 100%.
                event.Source.BackgroundColor = [1, 0.6 ,0.6];               % If it is, put the background of the active editfield of the vol % 
                uialert(app.UIFigure, ...                                   % that originated the warning in light red.
                    'The sum of the volumetric percentages cannot exceed 100%.', ...
                    'Invalid value');                                       % Creates the warning message.
                return
            end
            if total < 100                                                  % Check if the total is less than 100%.
                for i = 1:app.NumFuel                                       % Set all active vol % edifield backgrounds to light red
                    app.FuelComponents(i).volEditField.BackgroundColor ...
                        = [1, 0.6 ,0.6];        
                end 
                uialert(app.UIFigure, ...                                   % Creates the warning message.              
                    'The sum of the volumetric percentages should be 100%.', ...
                    'Invalid total');
                return
            end
        end

        function onFuelSelected(app, event)                                 % Function that validates fuel selection
            try                                                             % and updates the chemical formula.
                src = event.Source;                                         % Stores the object that was selected.
                idx = find(arrayfun(@(c) c.DropDown==src, ...               % Checks with @(c) c.DropDown = src which dropdrown
                    app.FuelComponents),1);                                 % originated the event (anonymous function).
                if isempty(idx), return; end                                % Applies this function to each element arrayfun(app.FuelComponents)
                selName = src.Value;                                        % and returns a logical vector (true/1).
                row = find(strcmp(app.FuelsTable{:,2}, selName),1);         % If the idx is empty ends the function.
                if isempty(row)                                             % Extract the second column (fuel name).
                    warning('Fuel "%s" not found.', selName);               % Check if there is a name in the second column.
                    return;
                end
                formula = app.FuelsTable{row,3}{1};                         % Extracts the LaTeX formula from the fuels table.
  % 
                app.FuelComponents(idx).formulaLabel.Text ...               % Displays the formula in LaTeX.
                    = ['$' formula '$'];
            catch ME
                    uialert( ...
        app.UIFigure, ...                                                   % Warning if the formula field is missing or there is an error.
        sprintf('Fuel "%s" does not exist', selName), ...
        'Erro', 'Icon','error');
            end
        end

        function onTabChanged(app, event)                                   % Function that activates when the tab is changed.
                                                                            % Acquires the application and event that led to the change.
            if event.NewValue == app.FuelTab                                % Verifica que a tab alterada
                app.CollectedFuelData = [];                                 % Clears the variable containing the data of the selected fuels

            end

            if event.OldValue == app.FuelTab && ...                         
                    event.NewValue ~= app.FuelTab
                vals = zeros(1, app.NumFuel);
                for i = 1:app.NumFuel
                    vals(i) = app.FuelComponents(i).volEditField.Value;
                end
                totalVol = sum(vals);

                if totalVol ~= 100
                    uialert(app.UIFigure, ...
                        'The sum of the volumetric percentages should be 100%.', ...
                        'invalid total');
                    app.TabGroup.SelectedTab = app.FuelTab;
                    return;
                end
                validateAndCollectFuels(app);
            end
            if event.NewValue == app.CombustionanalysisTab
                updateCombustionProducts(app);
            end
            if event.NewValue ~= app.FuelTab
                if isempty(app.CollectedFuelData)
                    return;
                end
                T = app.CollectedFuelData;
                if ~ismember("WeightPct", T.Properties.VariableNames)
                    try
                        M = T.molar_mass;
                        Vfrac = T.VolPct / 100;
                        mi = M .* Vfrac;
                        m_total = sum(mi);
                        Wpct = mi / m_total;
                        T.WeightPct = Wpct;
                        T.nec_O2 = T.WeightPct .* T.fm_o2;
                        T.nec_dry_air = T.WeightPct .* T.fm_dry_air;
                        app.CollectedFuelData = T;
                    catch ME
                        return;
                    end
                else

                end
                try
                    T = app.CollectedFuelData;
                    total_O2 = sum(T.nec_O2);
                    total_air = sum(T.nec_dry_air);

                    app.EditField_O2.Value = sprintf('%.3f', total_O2);        % O2 (kg/kg_fuel)
                    app.EditField_Dry_Air.Value = sprintf('%.3f', total_air);     % Air (kg/kg_fuel)
                catch ME


                end
                updateFinalFields(app);
                updateHumidAir(app);
                updateCombustionProducts(app);
                plotCombustionVsExcessAir(app);
            end
            computeCurrentFlameTemperature(app);
            plotFlameTemperatureVsExcessAir(app);
        end

        function validateAndCollectFuels(app)
            N = app.NumFuel;
            selNames = cell(N,1);
            for i = 1:N
                selNames{i} = app.FuelComponents(i).DropDown.Value;
            end
            for i = 1:N
                name = selNames{i};
                if isempty(name) || (ischar(name) && any(isnan(name))) ...
                        || (isstring(name) && ismissing(name))
                    app.FuelComponents(i).DropDown.BackgroundColor ...
                        = [1, 0.6, 0.6];
                    for j = 1:N
                        if j~=i
                            app.FuelComponents(j).DropDown.BackgroundColor = 'white';
                        end
                    end

                    uialert(app.UIFigure, ...
                        sprintf('Invalid selection  %d (empty fuel).', i), ...
                        'Validation error');
                    app.TabGroup.SelectedTab = app.FuelTab;
                    return
                end
            end
            [uniqueNames, ~, idxUnique] = unique(selNames);
            counts = histc(idxUnique, 1:numel(uniqueNames));
            dupValues = uniqueNames(counts > 1);
            if ~isempty(dupValues)
                for i = 1:N
                    if any(strcmp(selNames{i}, dupValues))
                        app.FuelComponents(i).DropDown.BackgroundColor ...
                            = [1,0.6,0.6];
                    else
                        app.FuelComponents(i).DropDown.BackgroundColor ...
                            = 'white';
                    end
                end
                uialert(app.UIFigure, ...
                    'The same fuel is being used more than once.', ...
                    'Validation error');
                app.TabGroup.SelectedTab = app.FuelTab;
                return
            end
            for i = 1:N
                app.FuelComponents(i).DropDown.BackgroundColor = 'white';
            end
            rows = zeros(N,1);
            vols = zeros(N,1);
            for i = 1:N
                c = app.FuelComponents(i);
                rows(i) = find(strcmp(app.FuelsTable{:,2}, ...
                    c.DropDown.Value),1);
                vols(i)   = c.volEditField.Value;
            end
            T = app.FuelsTable(rows,:);
            T.VolPct = vols;
            app.CollectedFuelData = T;
        end
        function updateExcessAirField(app)
            if app.CheckBoxExcessAir.Value
                app.EditFieldWout.Editable = 'on';
            else
                app.EditFieldWout.Value = '100';
                app.EditFieldWout.Editable = 'off';
            end
        end

        function updateFinalFields(app)
            ea = (app.EditFieldExcessAir.Value + 100);
            T  = app.CollectedFuelData;
            if isempty(T) || ~ismember('nec_O2', ...
                    T.Properties.VariableNames)
                return
            end
            finals_O2     = T.nec_O2      * ea/100;
            finals_dryAir = T.nec_dry_air * ea/100;
            app.EditField_O2_Final.Value       = sum(finals_O2);
            app.EditField_Dry_Air_Final.Value  = sum(finals_dryAir);
            updateHumidAir(app);
        end

        function updateHumidAir(app, ~)
            if app.CheckBoxHumidAir.Value
                state = 'on';
            else
                state = 'off';
            end
            app.SpinnerAtmosfericPressure.Enable = state;
            app.SpinnerRelativeHumidity.Enable   = state;
            app.SpinnerDryBulbTemperature.Enable = 'on';
            if ~app.CheckBoxHumidAir.Value
                app.EditField_Spec_Humidity.Value = 0;
                app.EditField_Air_H2O_Final.Value = 0;
            else
                T_C  = app.SpinnerDryBulbTemperature.Value;    % °C
                RH   = app.SpinnerRelativeHumidity.Value/100;  % (0–1)
                p_atm= app.SpinnerAtmosfericPressure.Value*1000; % kPa→Pa
                p_ws = XSteam('psat_T', T_C)*1e5;% psat em bar → Pa
                w = 0.622 * (RH*p_ws) / (p_atm - RH*p_ws);
                app.EditField_Spec_Humidity.Value = w;
                app.EditField_Air_H2O_Final.Value = w * ...
                    str2double( app.EditField_Dry_Air.Value ) ...
                    * (app.EditFieldExcessAir.Value + 100) /100;
            end
        end

        function updateCombustionProducts(app)

            T = app.CollectedFuelData;
            if isempty(T) || ~ismember("WeightPct", ...
                    T.Properties.VariableNames)
                return
            end

            T.pc_co2    = T.WeightPct .* T.fm_co2;
            app.EditField_CB_CO2.Value = sum(T.pc_co2);
            T.pc_h2o    = T.WeightPct .* T.fm_h2o;
            app.EditField_CB_H2O.Value = sum(T.pc_h2o) + app.EditField_Air_H2O_Final.Value;

            remDryAir   = app.EditField_Dry_Air_Final.Value - app.EditField_O2_Final.Value;
            app.EditField_CB_N2.Value = remDryAir;

            o2_supplied = app.EditField_O2_Final.Value;
            o2_stoich   = str2double(app.EditField_O2.Value);
            app.EditField_CB_O2.Value = o2_supplied - o2_stoich;
            app.CollectedFuelData = T;
        end

        function plotCombustionVsExcessAir(app)

            T = app.CollectedFuelData;
            if isempty(T) || ~ismember('WeightPct', T.Properties.VariableNames)
                return
            end
            range = app.ExcessofAirSlider.Value;
            minExcess = range(1);
            maxExcess = range(2);
            if maxExcess <= minExcess
                warning('Invalid excess air interval');
                return;
            end
            excessRange = linspace(minExcess, maxExcess, 1000);
            n = numel(excessRange);


            CO2 = zeros(1,n);
            H2O = zeros(1,n);
            N2  = zeros(1,n);
            O2  = zeros(1,n);


            fm_co2     = T.fm_co2;
            fm_h2o     = T.fm_h2o;
            fm_o2      = T.fm_o2;
            fm_dry_air = T.fm_dry_air;
            WeightPct  = T.WeightPct;
            w          = app.EditField_Spec_Humidity.Value;
            dryAir_Stoich = str2double(app.EditField_Dry_Air.Value); % kg/kg_fuel


            if isnan(w) || isnan(dryAir_Stoich)
                warning('Specific humidity or Dry Air invalid');
                return
            end


            for k = 1:n
                excess = excessRange(k);
                factor = (100 + excess)/100;


                O2_total     = sum(WeightPct .* fm_o2) * factor;
                dryAir_total = sum(WeightPct .* fm_dry_air) * factor;
                vapor_air    = w * dryAir_Stoich * factor;


                CO2(k) = sum(WeightPct .* fm_co2);
                H2O(k) = sum(WeightPct .* fm_h2o) + vapor_air;
                N2(k)  = dryAir_total - O2_total;
                O2(k)  = O2_total - sum(WeightPct .* fm_o2); 
            end


            cla(app.UIAxes_CB, 'reset');
            hold(app.UIAxes_CB, 'on');
            plot(app.UIAxes_CB, excessRange, CO2, '-', 'DisplayName','CO_2');
            plot(app.UIAxes_CB, excessRange, H2O, '-', 'DisplayName','H_2O');
            plot(app.UIAxes_CB, excessRange, N2,  '-', 'DisplayName','N_2');
            plot(app.UIAxes_CB, excessRange, O2,  '-', 'DisplayName','O_2');
            hold(app.UIAxes_CB, 'off');


            xlabel(app.UIAxes_CB, 'Excess Air [%]');
            ylabel(app.UIAxes_CB, 'Combustion products [kg/kg_{fuel}]');
            legend(app.UIAxes_CB, 'Location', 'northwest');
            title(app.UIAxes_CB, ' ');
            grid(app.UIAxes_CB, 'on');
            app.UIAxes_CB.XLim = [range(1), range(2)];

        end
        function computeCurrentFlameTemperature(app)
            T = app.CollectedFuelData;
            if isempty(T) || ~all(ismember({'WeightPct','hf_o','m'}, T.Properties.VariableNames))
                return
            end

            m        = sum(T.m);
            n        = m;
            hf_o     = sum(T.hf_o);
            T_air    = app.SpinnerDryBulbTemperature.Value;
            RH       = app.SpinnerRelativeHumidity.Value / 100;
            humidity = app.CheckBoxHumidAir.Value;
            Q_in     = app.EditFieldQin.Value;
            W_in     = app.EditFieldWin.Value;
            Q_out    = app.EditFieldQout.Value;
            W_out    = app.EditFieldWout.Value;
            exc_air  = (app.EditFieldExcessAir.Value + 100) / 100;

            try
                T_p_K = flame_temperature(m, n, hf_o, T_air, exc_air, RH, humidity, Q_in, W_in, Q_out, W_out);
                T_p_C = double(T_p_K - 273.15);
                app.EditFieldT_p.Value = T_p_C;
            catch
                app.EditFieldT_p.Value = NaN;
            end

            plotFlameTemperatureVsExcessAir(app);
        end

        function plotFlameTemperatureVsExcessAir(app)
            T = app.CollectedFuelData;
            if isempty(T) || ~all(ismember({'WeightPct','hf_o','m'}, T.Properties.VariableNames))
                return
            end
            m = sum(T.m);
            n = m;
            hf_o = sum(T.hf_o);
            T_air = app.SpinnerDryBulbTemperature.Value;
            RH = app.SpinnerRelativeHumidity.Value / 100;
            humidity = app.CheckBoxHumidAir.Value;
            Q_in  = app.EditFieldQin.Value;
            W_in  = app.EditFieldWin.Value;
            Q_out = app.EditFieldQout.Value;
            W_out = app.EditFieldWout.Value;
            range = app.SliderT_p.Value;
            excessRange = linspace(range(1), range(2), 20); 
            Tp = zeros(size(excessRange));

            for i = 1:numel(excessRange)
                exc = (excessRange(i) + 100) / 100;
                try
                    Tp(i) = flame_temperature(m, n, hf_o, T_air, exc, RH, humidity, Q_in, W_in, Q_out, W_out) - 273.15;
                catch
                    Tp(i) = NaN;
                end
            end
            cla(app.UIAxesT_p, 'reset');
            plot(app.UIAxesT_p, excessRange, Tp, 'b', 'LineWidth', 1);
            xlabel(app.UIAxesT_p, 'Excess air [%]');
            ylabel(app.UIAxesT_p, 'Combustion products temperature [ºC]');
            grid(app.UIAxesT_p, 'on');
            app.UIAxesT_p.XLim = [range(1), range(2)];
        end

        function calcOstwaldTriangleTheo(app)

            COData = getappdata(0, 'COData');
            O2Data = getappdata(0, 'O2Data');
            TemperatureData = getappdata(0, 'TemperatureData');

            CO_probe = str2double(COData);
            O2_probe = str2double(O2Data);
            TemperatureValue = str2double(TemperatureData);


            if isnan(CO_probe) || isnan(O2_probe) || isnan(TemperatureValue)
                uialert(app.UIFigure, ...
                    'Invalid or missing probe data. Please check the input format or connection.', ...
                    'Probe Error');
                return;
            end

            app.EditFieldOstT.Value = TemperatureValue;
            T = app.CollectedFuelData;

            if isempty(T) || ~ismember("WeightPct", T.Properties.VariableNames)

                return
            end


            T.pc_inc_co  = (T.WeightPct*100./T.molar_mass) * 1;
            T.pc_inc_n2  = (T.WeightPct*100./T.molar_mass) .* T.fm_n2;
            T.pc_inc_o2  = (T.WeightPct*100./T.molar_mass) * 0.5;

            total_co  = sum(T.pc_inc_co);
            total_n2  = sum(T.pc_inc_n2);
            total_o2  = sum(T.pc_inc_o2);
            total_sum = total_co + total_n2 + total_o2;

            vol_CO_point_C = (total_co / total_sum)*100;
            vol_O2_point_C = (total_o2 / total_sum)*100;

            app.CollectedFuelData = T;

            O2_total     = sum(T.WeightPct .* T.fm_o2);
            dryAir_total = sum(T.WeightPct .* T.fm_dry_air);
            CO2 = sum(T.WeightPct .* T.fm_co2);
            H2O = sum(T.WeightPct .* T.fm_h2o);
            N2  = dryAir_total - O2_total;
            O2  = O2_total - sum(T.WeightPct .* T.fm_o2);
            CO2_point_A = (CO2 /(CO2+H2O+N2+O2))*100;

            if app.TabGroup.SelectedTab ~= app.OstwaldTriangleTab
                return;
            end

            cla(app.UIAxesOstwald, 'reset')

            if isnan(CO_probe) || isnan(O2_probe)
                uialert(app.UIFigure, ...
                    'No measures available. Please try to use probe dashboard in first place.', ...
                    'Missing Probe Data');
                return;
            end

            Ax = 0;
            Ay = CO2_point_A;
            Bx = 21;
            By = 0;
            Cx = vol_O2_point_C;
            Cy = 0;


            m_ab = (By - Ay) / (Bx - Ax);
            b_ab = Ay - m_ab * Ax;


            m_perp = -1 / m_ab;
            b_perp = Cy - m_perp * Cx;


            xD = (b_perp - b_ab) / (m_ab - m_perp);
            yD = m_ab * xD + b_ab;


            x_CD = [Cx, xD];
            y_CD = [Cy, yD];

            t = CO_probe / vol_CO_point_C;
            xP = (1 - t) * xD + t * Cx;
            yP = (1 - t) * yD + t * Cy;


            vCD = [xD - Cx, yD - Cy];
            v_perp_CD = [-vCD(2), vCD(1)];
            m_perp_CD = v_perp_CD(2) / v_perp_CD(1);
            b_perp_P = yP - m_perp_CD * xP;


            xV = O2_probe;
            yV_top = m_ab * xV + b_ab;
            yV_bottom = 0;


            xK = xV;
            yK = m_perp_CD * xK + b_perp_P;
            yK = min(yK, yV_top);

            yK_limite = m_ab * xK + b_ab;
            if yK < 0 || xK < 0 || yK > yK_limite
                uialert(app.UIFigure, ...
                    ['The calculated point is out of the valid range for the Ostwald triangle.' newline ...
                    'Please check the probe input values (CO and O₂).'], ...
                    'Invalid data');
                cla(app.UIAxesOstwald, 'reset');
                CO_probe = NaN;
                O2_probe = NaN;
                return;
            end

            hold(app.UIAxesOstwald, 'on');
            plot(app.UIAxesOstwald, [Ax, Bx], [Ay, By], 'k-');
            plot(app.UIAxesOstwald, [Ax, Cx], [Ay, Cy], 'b--');
            plot(app.UIAxesOstwald, x_CD, y_CD, 'r:');
            text(app.UIAxesOstwald, xP + 0.5, yP + 0.5, ...
                ['CO = ', num2str(CO_probe, '%.2f') '%'], ...
                'Color', 'm', 'FontSize', 10);


            x_perp = linspace(xP, xK, 100);
            y_perp = m_perp_CD * x_perp + b_perp_P;
            plot(app.UIAxesOstwald, x_perp, y_perp, 'c-.');


            plot(app.UIAxesOstwald, [xV, xV], [yV_bottom, yK], 'g--');


            plot(app.UIAxesOstwald, [0, xK], [yK, yK], 'k-.');


            vAC = [Cx - Ax, Cy - Ay];
            m_AC = vAC(2) / vAC(1);
            b_E = yK - m_AC * xK;


            xE = (b_ab - b_E) / (m_AC - m_ab);
            yE = m_AC * xE + b_E;


            plot(app.UIAxesOstwald, [xK, xE], [yK, yE], 'm--');
            plot(app.UIAxesOstwald, xE, yE, 'r', 'MarkerSize', 6);
            text(app.UIAxesOstwald, xE + 0.5, yE, ...
                ['E = ', num2str(1 / (dot([xE - Bx, yE - By], [Ax - Bx, Ay - By]) / dot([Ax - Bx, Ay - By], [Ax - Bx, Ay - By])), '%.3f')], ...
                'Color', 'r');


            vAB = [Ax - Bx, Ay - By];
            vBE = [xE - Bx, yE - By];
            AB_mag2 = dot(vAB, vAB);
            lambda = dot(vBE, vAB) / AB_mag2;
            Ostwald_E = (1 / lambda)*100 -100;


            xlabel(app.UIAxesOstwald, 'O₂ [% vol]');
            ylabel(app.UIAxesOstwald, 'CO₂ [% vol]');
            app.UIAxesOstwald.DataAspectRatio = [1 1 1];
            grid(app.UIAxesOstwald, 'on');
            Ostwald_CO2 = yK;


            app.EditFieldOstE.Value   = Ostwald_E;
            app.EditFieldOstCO2.Value = Ostwald_CO2;
            app.EditFieldOstCO.Value  = CO_probe;
            app.EditFieldOstO2.Value  = O2_probe;

        end

        function ClearAllButtonPushed(app, ~)
            app.CollectedFuelData = [];
            app.NumFuel = 1;
            updateFuelFields(app);

            for k = 1:numel(app.FuelComponents)
                dd = app.FuelComponents(k).DropDown;
                ve = app.FuelComponents(k).volEditField;
                if ~isempty(dd.Items)
                    dd.Value = dd.Items{1};
                end
                ve.Value = [];
                ve.BackgroundColor = 'white';
                app.FuelComponents(k).formulaLabel.Text = '';
            end
            app.EditField_O2.Value = '';
            app.EditField_Dry_Air.Value = '';
            app.EditField_Spec_Humidity.Value = 0;
            app.EditField_O2_Final.Value = 0;
            app.EditField_Dry_Air_Final.Value = 0;
            app.EditField_Air_H2O_Final.Value = 0;
            app.EditFieldExcessAir.Value = 0;
            app.SpinnerAtmosfericPressure.Value = 101.325;
            app.SpinnerRelativeHumidity.Value = 60;
            app.SpinnerDryBulbTemperature.Value = 25;
            app.EditField_CB_CO2.Value = 0;
            app.EditField_CB_H2O.Value = 0;
            app.EditField_CB_N2.Value  = 0;
            app.EditField_CB_O2.Value  = 0;
            app.EditFieldT_p.Value =  0;
            app.EditFieldQin.Value  = 0;
            app.EditFieldWin.Value  = 0;
            app.EditFieldQout.Value = 0;
            app.EditFieldWout.Value = 0;
            app.EditFieldOstE.Value   = [];
            app.EditFieldOstCO2.Value = [];
            app.EditFieldOstCO.Value  = [];
            app.EditFieldOstO2.Value  = [];
            app.EditFieldOstT.Value   = [];
            cla(app.UIAxes_CB, 'reset');
            cla(app.UIAxesT_p, 'reset');
            cla(app.UIAxesOstwald, 'reset');
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            addpath('bin');
            app.UIFigure.Name = 'Flame Solver v1.0';
            app.UIFigure.Icon = '.\bin\icon.png';
            addpath(fullfile(fileparts(mfilename('fullpath')), 'bin','xsteam'));
            clc;

            addlistener( app.TabGroup, ...
                'SelectionChanged', ...
                @(src,ev) onTabChanged(app,ev) );

            addlistener(app.EditFieldExcessAir, 'ValueChanged', ...
                @(src,ev) updateFinalFields(app));

            app.EditFieldExcessAir.Limits = [0 1000];
            app.EditFieldExcessAir.LowerLimitInclusive = 'on';
            app.EditFieldExcessAir.UpperLimitInclusive = 'on';
            app.EditFieldExcessAir.Value = 0;


            fcn = createCallbackFcn(app, @updateHumidAir, true);

            app.CheckBoxHumidAir.ValueChangedFcn         = fcn;
            app.SpinnerAtmosfericPressure.ValueChangedFcn= fcn;
            app.SpinnerRelativeHumidity.ValueChangedFcn  = fcn;
            app.SpinnerDryBulbTemperature.ValueChangedFcn= fcn;
            app.EditField_Dry_Air_Final.ValueChangedFcn = fcn;

            app.SpinnerAtmosfericPressure.Enable = 'off';
            app.SpinnerRelativeHumidity.Enable   = 'off';
            app.SpinnerDryBulbTemperature.Enable = 'off';
            updateHumidAir(app);

            addlistener(app.CheckBoxHumidAir, 'ValueChanged', ...
                @(src,ev) updateHumidAir(app,ev));


            addlistener(app.TabGroup, 'SelectionChanged', @(s,e) onTabChanged(app,e));

            addlistener(app.ExcessofAirSlider, 'ValueChanged', ...
                @(src, ev) plotCombustionVsExcessAir(app));
            addlistener(app.EditFieldQin,  'ValueChanged', @(src,ev) computeCurrentFlameTemperature(app));
            addlistener(app.EditFieldWin,  'ValueChanged', @(src,ev) computeCurrentFlameTemperature(app));
            addlistener(app.EditFieldQout, 'ValueChanged', @(src,ev) computeCurrentFlameTemperature(app));
            addlistener(app.EditFieldWout, 'ValueChanged', @(src,ev) computeCurrentFlameTemperature(app));
            addlistener(app.SliderT_p, 'ValueChanged', @(s,e) plotFlameTemperatureVsExcessAir(app));


            sufixes = {'', '_2', '_3', '_4', '_5', '_6'};
            app.FuelComponents(numel(sufixes)) = struct( ...
                'DropDown',     [], ...
                'volEditField', [], ...
                'volLabel',     [], ...
                'formulaLabel', [] );
            for k = 1:numel(sufixes)
                app.FuelComponents(k).DropDown     = app.(['DropDown'     sufixes{k}]);
                app.FuelComponents(k).volEditField = app.(['volEditField' sufixes{k}]);
                app.FuelComponents(k).volLabel     = app.(['volLabel'     sufixes{k}]);
                app.FuelComponents(k).formulaLabel = app.(['Label_' num2str(k)]);
                app.FuelComponents(k).formulaLabel.Visible = 'off';
            end


            fuelsFile = fullfile(fileparts(mfilename('fullpath')), 'bin','fuels.xlsx');
            T = readtable(fuelsFile);
            idCol    = T{:,1};
            validRows = ~isnan(idCol);
            T = T(validRows,:);
            vars   = T.Properties.VariableNames;
            toKeep = false(1,numel(vars));
            for k = 1:numel(vars)
                col = T.(vars{k});
                if isnumeric(col)
                    toKeep(k) = any(~isnan(col));
                elseif iscellstr(col) || isstring(col)
                    toKeep(k) = any(~ismissing(col));
                else
                    toKeep(k) = true;
                end
            end
            T = T(:, vars(toKeep));
            app.FuelsTable = T;
            names = app.FuelsTable{:,2};
            for k = 1:numel(app.FuelComponents)
                dd = app.FuelComponents(k).DropDown;
                dd.Items = names;
                dd.Value = names{1};
                dd.ValueChangedFcn = @(src, ev) onFuelSelected(app, ev);

            end
            app.NumFuel = 1;
            updateFuelFields(app);
            for k = 1:numel(app.FuelComponents)
                app.FuelComponents(k).volEditField.Limits = [0, 500];
                app.FuelComponents(k).volEditField.LowerLimitInclusive = 'off';
                app.FuelComponents(k).volEditField.UpperLimitInclusive = 'on';
                app.FuelComponents(k).valueChangedFcn = createCallbackFcn(app, @fuelValueChanged, true);
            end
            for k = 1:numel(app.FuelComponents)
                app.FuelComponents(k).volEditField.ValueChangedFcn = createCallbackFcn(app, @fuelValueChanged, true);
            end
            onFuelSelected(app, struct('Source',app.FuelComponents(1).DropDown));

        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            run('bin/about.mlapp');
        end

        % Button pushed function: Button_minus
        function Button_minusPushed(app, event)
            app.NumFuel = app.NumFuel - 1;
            updateFuelFields(app);
        end

        % Button pushed function: Button_plus
        function Button_plusPushed(app, event)
            app.NumFuel = app.NumFuel + 1;
            updateFuelFields(app);
        end

        % Menu selected function: ProbedashboardMenu
        function ProbedashboardMenuSelected(app, event)
            run('bin/probe_dashboard.mlapp');

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            calcOstwaldTriangleTheo(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxesOstwald, 'reset');


            app.EditFieldOstE.Value     = [];
            app.EditFieldOstCO2.Value   = [];
            app.EditFieldOstCO.Value    = [];
            app.EditFieldOstO2.Value    = [];
            app.EditFieldOstT.Value     = [];


        end

        % Menu selected function: GeneratereportMenu
        function GeneratereportMenuSelected(app, event)


            import mlreportgen.report.*
            import mlreportgen.dom.*

            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            filename  = fullfile(tempdir, ['FlameSolver_Report_' timestamp '.pdf']);

            rpt = Report(filename, 'pdf');
            rpt.Layout.Landscape = false;

            add(rpt, Paragraph('Flame Solver Report'));
            add(rpt, Paragraph(' '));


            add(rpt, Paragraph('---------- Fuel ----------'));
            Tfuel = app.CollectedFuelData;
            if ~isempty(Tfuel)
                names = Tfuel{:,2};
                vols  = Tfuel.VolPct;
                Ttbl  = table(names, vols, 'VariableNames', {'Fuel','VolPct'});
                add(rpt, BaseTable(Ttbl));
            else
                add(rpt, Paragraph('No fuel data available.'));
            end
            add(rpt, Paragraph(' '));
            add(rpt, Paragraph('---------- Combustion Analysis ----------'));
            vCO2 = app.EditField_CB_CO2.Value;
            vH2O = app.EditField_CB_H2O.Value;
            vN2  = app.EditField_CB_N2.Value;
            vO2  = app.EditField_CB_O2.Value;
            if all(isnumeric([vCO2 vH2O vN2 vO2]))
                Tcomb = table( ...
                    ["CO₂"; "H₂O"; "N₂"; "O₂"], ...
                    [vCO2; vH2O; vN2; vO2], ...
                    'VariableNames', {'Species','Value'} ...
                    );
                add(rpt, BaseTable(Tcomb));
            else
                add(rpt, Paragraph('No combustion data available.'));
            end
            add(rpt, Paragraph(' '));


            add(rpt, Paragraph('---------- Flame Temperature ----------'));
            Tp     = app.EditFieldT_p.Value;
            Qin    = app.EditFieldQin.Value;
            Win    = app.EditFieldWin.Value;
            Qout   = app.EditFieldQout.Value;
            Wout   = app.EditFieldWout.Value;
            if isnumeric(Tp)
                add(rpt, Paragraph(sprintf('Flame Temperature (°C): %.2f', Tp)));
                add(rpt, Paragraph(sprintf('Q_in  : %.0f', Qin)));
                add(rpt, Paragraph(sprintf('W_in  : %.0f', Win)));
                add(rpt, Paragraph(sprintf('Q_out : %.0f', Qout)));
                add(rpt, Paragraph(sprintf('W_out : %.0f', Wout)));

                fig2 = uifigure('Visible','off'); copyobj(app.UIAxesT_p, fig2);
                img2 = fullfile(tempdir, ['tempcurve_' timestamp '.png']);
                exportgraphics(fig2.Children, img2, 'Resolution',200);
                add(rpt, Image(img2));
            else
                add(rpt, Paragraph('No flame temperature available.'));
            end
            add(rpt, Paragraph(' '));


            add(rpt, Paragraph('---------- Ostwald Triangle ----------'));
            co_probe    = app.EditFieldOstCO.Value;
            o2_probe    = app.EditFieldOstO2.Value;
            co2_k       = app.EditFieldOstCO2.Value;
            E_value     = app.EditFieldOstE.Value;
            T_probe     = app.EditFieldOstT.Value;

            if isprop(app,'vol_CO_point_C') && isprop(app,'vol_O2_point_C')
                Cco = app.vol_CO_point_C;
                Co2 = app.vol_O2_point_C;
                add(rpt, Paragraph(sprintf('Triangle C: CO  = %.2f%%, O₂ = %.2f%%', Cco, Co2)));
            end
            add(rpt, Paragraph(sprintf('Probe CO   : %g', co_probe)));
            add(rpt, Paragraph(sprintf('Probe O₂   : %g', o2_probe)));
            add(rpt, Paragraph(sprintf('Ostwald CO₂: %.4f', co2_k)));
            add(rpt, Paragraph(sprintf('Ostwald Excess of air  : %.6f', E_value)));
            add(rpt, Paragraph(sprintf('Temperature: %.2f °C', T_probe)));

            fig3 = uifigure('Visible','off'); copyobj(app.UIAxesOstwald, fig3);
            img3 = fullfile(tempdir, ['ostwald_' timestamp '.png']);
            exportgraphics(fig3.Children, img3, 'Resolution',200);
            add(rpt, Image(img3));

            close(rpt);
            rptview(rpt);

        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)

            app.CollectedFuelData = [];
            app.NumFuel = 1;
            updateFuelFields(app);

            for k = 1:numel(app.FuelComponents)
                dd = app.FuelComponents(k).DropDown;
                ve = app.FuelComponents(k).volEditField;
                if ~isempty(dd.Items)
                    dd.Value = dd.Items{1};
                end
                ve.Value = [];
                ve.BackgroundColor = 'white';
                app.FuelComponents(k).formulaLabel.Text = '';
            end
            app.EditField_O2.Value = '';
            app.EditField_Dry_Air.Value = '';
            app.EditField_Spec_Humidity.Value = 0;
            app.EditField_O2_Final.Value = 0;
            app.EditField_Dry_Air_Final.Value = 0;
            app.EditField_Air_H2O_Final.Value = 0;
            app.EditFieldExcessAir.Value = 0;
            app.SpinnerAtmosfericPressure.Value = 101.325;
            app.SpinnerRelativeHumidity.Value = 60;
            app.SpinnerDryBulbTemperature.Value = 25;
            app.EditField_CB_CO2.Value = 0;
            app.EditField_CB_H2O.Value = 0;
            app.EditField_CB_N2.Value  = 0;
            app.EditField_CB_O2.Value  = 0;
            app.EditFieldT_p.Value =  0;
            app.EditFieldQin.Value  = 0;
            app.EditFieldWin.Value  = 0;
            app.EditFieldQout.Value = 0;
            app.EditFieldWout.Value = 0;
            app.EditFieldOstE.Value   = [];
            app.EditFieldOstCO2.Value = [];
            app.EditFieldOstCO.Value  = [];
            app.EditFieldOstO2.Value  = [];
            app.EditFieldOstT.Value   = [];
            cla(app.UIAxes_CB, 'reset');
            cla(app.UIAxesT_p, 'reset');

        end

        % Menu selected function: LoadexampleMenu
        function LoadexampleMenuSelected(app, event)

            ClearAllButtonPushed(app, event);
            app.NumFuel = 1;
            updateFuelFields(app);
            fuel = 'n-Octane';
            names = app.FuelComponents(1).DropDown.Items;
            idx = find(strcmp(names, fuel), 1);
            if isempty(idx)
                uialert(app.UIFigure, 'Fuel not found!', 'Erro');
                return;
            end
            app.FuelComponents(1).DropDown.Value = names{idx};
            app.FuelComponents(1).volEditField.Value = 100;
            onFuelSelected(app, struct('Source', app.FuelComponents(1).DropDown));
            fuelValueChanged(app, struct('Source', app.FuelComponents(1).volEditField));
            app.EditFieldExcessAir.Value = 15;
            app.CheckBoxHumidAir.Value = true;
            app.SpinnerRelativeHumidity.Value = 90;
            app.SpinnerDryBulbTemperature.Value = 25;
            app.EditFieldQout.Value = 75000;
            app.EditFieldWout.Value = 120000;
            validateAndCollectFuels(app);
            updateFinalFields(app);
            updateCombustionProducts(app);
            computeCurrentFlameTemperature(app);
            plotCombustionVsExcessAir(app);
            plotFlameTemperatureVsExcessAir(app);


        end

        % Menu selected function: ProjectreportMenu
        function ProjectreportMenuSelected(app, event)
            open('./docs/Project_Report.pdf');
        end

        % Menu selected function: ExampledocumentationMenu
        function ExampledocumentationMenuSelected(app, event)
            open('./docs/Example.pdf');
        end

        % Menu selected function: HelpMenu_3
        function HelpMenu_3Selected(app, event)
            open('./docs/Help.pdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = ' File ';
            app.ExampleMenu = uimenu(app.FileMenu);
            app.ExampleMenu.Text = '📜 Example';
            app.LoadexampleMenu = uimenu(app.ExampleMenu);
            app.LoadexampleMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadexampleMenuSelected, true);
            app.LoadexampleMenu.Text = '📤Load example';
            app.ExampledocumentationMenu = uimenu(app.ExampleMenu);
            app.ExampledocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @ExampledocumentationMenuSelected, true);
            app.ExampledocumentationMenu.Text = '🗃️ Example documentation';
            app.GeneratereportMenu = uimenu(app.FileMenu);
            app.GeneratereportMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneratereportMenuSelected, true);
            app.GeneratereportMenu.Text = '📖 Generate report';
            app.ClearMenu = uimenu(app.FileMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = '🧹Clear';
            app.ProbeMenu = uimenu(app.UIFigure);
            app.ProbeMenu.Text = ' Probe ';
            app.ProbedashboardMenu = uimenu(app.ProbeMenu);
            app.ProbedashboardMenu.MenuSelectedFcn = createCallbackFcn(app, @ProbedashboardMenuSelected, true);
            app.ProbedashboardMenu.Text = '🔎Probe dashboard';
            app.ProjectreportMenu = uimenu(app.UIFigure);
            app.ProjectreportMenu.MenuSelectedFcn = createCallbackFcn(app, @ProjectreportMenuSelected, true);
            app.ProjectreportMenu.Text = 'Project report';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = ' Help ';
            app.HelpMenu_3 = uimenu(app.HelpMenu);
            app.HelpMenu_3.MenuSelectedFcn = createCallbackFcn(app, @HelpMenu_3Selected, true);
            app.HelpMenu_3.Text = '📚 Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'ⓘ About...';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FuelTab = uitab(app.TabGroup);
            app.FuelTab.Title = 'Fuel';
            app.FuelcomponentsPanel = uipanel(app.FuelTab);
            app.FuelcomponentsPanel.Title = 'Fuel components';
            app.GridLayout = uigridlayout(app.FuelcomponentsPanel);
            app.Button_plus = uibutton(app.GridLayout, 'push');
            app.Button_plus.ButtonPushedFcn = createCallbackFcn(app, @Button_plusPushed, true);
            app.Button_plus.Text = '+';
            app.Button_minus = uibutton(app.GridLayout, 'push');
            app.Button_minus.ButtonPushedFcn = createCallbackFcn(app, @Button_minusPushed, true);
            app.Button_minus.Text = '-';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown_2 = uidropdown(app.GridLayout);
            app.DropDown_3 = uidropdown(app.GridLayout);
            app.DropDown_4 = uidropdown(app.GridLayout);
            app.DropDown_5 = uidropdown(app.GridLayout);
            app.DropDown_6 = uidropdown(app.GridLayout);
            app.Label_1 = uilabel(app.GridLayout);
            app.Label_1.Interpreter = 'latex';
            app.Label_1.Text = '';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Interpreter = 'latex';
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Interpreter = 'latex';
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Interpreter = 'latex';
            app.Label_6.Text = '';
            app.volLabel = uilabel(app.GridLayout);
            app.volLabel.Interpreter = 'tex';
            app.volLabel.Text = '%_{vol}';
            app.volEditField = uieditfield(app.GridLayout, 'numeric');
            app.volEditField.AllowEmpty = 'on';
            app.volLabel_2 = uilabel(app.GridLayout);
            app.volLabel_2.Interpreter = 'tex';
            app.volLabel_2.Text = '%_{vol}';
            app.volEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.volEditField_2.AllowEmpty = 'on';
            app.volLabel_3 = uilabel(app.GridLayout);
            app.volLabel_3.Interpreter = 'tex';
            app.volLabel_3.Text = '%_{vol}';
            app.volEditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.volEditField_3.AllowEmpty = 'on';
            app.volLabel_4 = uilabel(app.GridLayout);
            app.volLabel_4.Interpreter = 'tex';
            app.volLabel_4.Text = '%_{vol}';
            app.volEditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.volEditField_4.AllowEmpty = 'on';
            app.volLabel_5 = uilabel(app.GridLayout);
            app.volLabel_5.Interpreter = 'tex';
            app.volLabel_5.Text = '%_{vol}';
            app.volEditField_5 = uieditfield(app.GridLayout, 'numeric');
            app.volEditField_5.AllowEmpty = 'on';
            app.volLabel_6 = uilabel(app.GridLayout);
            app.volLabel_6.Interpreter = 'tex';
            app.volLabel_6.Text = '%_{vol}';
            app.volEditField_6 = uieditfield(app.GridLayout, 'numeric');
            app.volEditField_6.AllowEmpty = 'on';
            app.AirTab = uitab(app.TabGroup);
            app.AirTab.Title = 'Air';
            app.ExcessairPanel = uipanel(app.AirTab);
            app.ExcessairPanel.Title = 'Excess air';
            app.GridLayout5 = uigridlayout(app.ExcessairPanel);
            app.EditFieldExcessAir = uieditfield(app.GridLayout5, 'numeric');
            app.Label_7 = uilabel(app.GridLayout5);
            app.Label_7.Text = '%';
            app.IncludeairhumidityinthestudyLabel = uilabel(app.GridLayout5);
            app.IncludeairhumidityinthestudyLabel.Text = 'Include air humidity in the study?';
            app.CheckBoxHumidAir = uicheckbox(app.GridLayout5);
            app.CheckBoxHumidAir.Text = '';
            app.StoichiometricairPanel = uipanel(app.AirTab);
            app.StoichiometricairPanel.Title = 'Stoichiometric air';
            app.GridLayout3 = uigridlayout(app.StoichiometricairPanel);
            app.O_2Label = uilabel(app.GridLayout3);
            app.O_2Label.Interpreter = 'tex';
            app.O_2Label.Text = 'O_2';
            app.kgkg_fuelLabel = uilabel(app.GridLayout3);
            app.kgkg_fuelLabel.Interpreter = 'tex';
            app.kgkg_fuelLabel.Text = 'kg/kg_{fuel}';
            app.EditField_O2 = uieditfield(app.GridLayout3, 'text');
            app.EditField_O2.Editable = 'off';
            app.DryairLabel = uilabel(app.GridLayout3);
            app.DryairLabel.Text = 'Dry air';
            app.kgkg_fuelLabel_2 = uilabel(app.GridLayout3);
            app.kgkg_fuelLabel_2.Interpreter = 'tex';
            app.kgkg_fuelLabel_2.Text = 'kg/kg_{fuel}';
            app.EditField_Dry_Air = uieditfield(app.GridLayout3, 'text');
            app.EditField_Dry_Air.Editable = 'off';
            app.CombustionairPanel = uipanel(app.AirTab);
            app.CombustionairPanel.Title = 'Combustion air';
            app.GridLayout2 = uigridlayout(app.CombustionairPanel);
            app.O_2Label_2 = uilabel(app.GridLayout2);
            app.O_2Label_2.Interpreter = 'tex';
            app.O_2Label_2.Text = 'O_2';
            app.kgkg_fuelLabel_3 = uilabel(app.GridLayout2);
            app.kgkg_fuelLabel_3.Interpreter = 'tex';
            app.kgkg_fuelLabel_3.Text = 'kg/kg_{fuel}';
            app.DryairLabel_2 = uilabel(app.GridLayout2);
            app.DryairLabel_2.Text = 'Dry air';
            app.kgkg_fuelLabel_4 = uilabel(app.GridLayout2);
            app.kgkg_fuelLabel_4.Interpreter = 'tex';
            app.kgkg_fuelLabel_4.Text = 'kg/kg_{fuel}';
            app.H_2OLabel_2 = uilabel(app.GridLayout2);
            app.H_2OLabel_2.Interpreter = 'tex';
            app.H_2OLabel_2.Text = 'H_2O';
            app.kgkg_fuelLabel_9 = uilabel(app.GridLayout2);
            app.kgkg_fuelLabel_9.Interpreter = 'tex';
            app.kgkg_fuelLabel_9.Text = 'kg/kg_{fuel}';
            app.EditField_O2_Final = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_O2_Final.ValueDisplayFormat = '%.3f';
            app.EditField_O2_Final.Editable = 'off';
            app.EditField_Dry_Air_Final = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_Dry_Air_Final.ValueDisplayFormat = '%.3f';
            app.EditField_Dry_Air_Final.Editable = 'off';
            app.EditField_Air_H2O_Final = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_Air_H2O_Final.ValueDisplayFormat = '%.3f';
            app.EditField_Air_H2O_Final.Editable = 'off';
            app.AirpropertiesPanel = uipanel(app.AirTab);
            app.AirpropertiesPanel.Title = 'Air properties';
            app.GridLayout4 = uigridlayout(app.AirpropertiesPanel);
            app.AtmosfericpressureLabel = uilabel(app.GridLayout4);
            app.AtmosfericpressureLabel.Text = 'Atmosferic pressure';
            app.kPaLabel = uilabel(app.GridLayout4);
            app.kPaLabel.Text = 'kPa';
            app.RelativehumidityLabel = uilabel(app.GridLayout4);
            app.RelativehumidityLabel.Text = 'Relative humidity';
            app.Label_8 = uilabel(app.GridLayout4);
            app.Label_8.Text = '%';
            app.DrybulbtemperatureLabel = uilabel(app.GridLayout4);
            app.DrybulbtemperatureLabel.Text = 'Dry bulb temperature';
            app.CLabel = uilabel(app.GridLayout4);
            app.CLabel.Text = 'ºC';
            app.SpinnerAtmosfericPressure = uispinner(app.GridLayout4);
            app.SpinnerAtmosfericPressure.ValueDisplayFormat = '%.3f';
            app.SpinnerAtmosfericPressure.Value = 101.325;
            app.SpinnerRelativeHumidity = uispinner(app.GridLayout4);
            app.SpinnerRelativeHumidity.ValueDisplayFormat = '%.2f';
            app.SpinnerRelativeHumidity.Value = 60;
            app.SpinnerDryBulbTemperature = uispinner(app.GridLayout4);
            app.SpinnerDryBulbTemperature.ValueDisplayFormat = '%.2f';
            app.SpinnerDryBulbTemperature.Value = 25;
            app.EditField_Spec_Humidity = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_Spec_Humidity.ValueDisplayFormat = '%.4f';
            app.EditField_Spec_Humidity.Editable = 'off';
            app.SpecifichumidityLabel = uilabel(app.GridLayout4);
            app.SpecifichumidityLabel.Text = 'Specific humidity';
            app.kg_H_2Okg_airLabel = uilabel(app.GridLayout4);
            app.kg_H_2Okg_airLabel.Interpreter = 'tex';
            app.kg_H_2Okg_airLabel.Text = 'kg_{H_2O}/kg_{air}';
            app.CombustionanalysisTab = uitab(app.TabGroup);
            app.CombustionanalysisTab.Title = 'Combustion analysis';
            app.GridLayout9 = uigridlayout(app.CombustionanalysisTab);
            app.CombustionproductsPanel = uipanel(app.GridLayout9);
            app.CombustionproductsPanel.Title = 'Combustion products';
            app.GridLayout6 = uigridlayout(app.CombustionproductsPanel);
            app.CO_2Label = uilabel(app.GridLayout6);
            app.CO_2Label.Interpreter = 'tex';
            app.CO_2Label.Text = 'CO_2';
            app.kgkg_fuelLabel_5 = uilabel(app.GridLayout6);
            app.kgkg_fuelLabel_5.Interpreter = 'tex';
            app.kgkg_fuelLabel_5.Text = 'kg/kg_{fuel}';
            app.H_2OLabel = uilabel(app.GridLayout6);
            app.H_2OLabel.Interpreter = 'tex';
            app.H_2OLabel.Text = 'H_2O';
            app.kgkg_fuelLabel_6 = uilabel(app.GridLayout6);
            app.kgkg_fuelLabel_6.Interpreter = 'tex';
            app.kgkg_fuelLabel_6.Text = 'kg/kg_{fuel}';
            app.N_2Label = uilabel(app.GridLayout6);
            app.N_2Label.Interpreter = 'tex';
            app.N_2Label.Text = 'N_2';
            app.kgkg_fuelLabel_7 = uilabel(app.GridLayout6);
            app.kgkg_fuelLabel_7.Interpreter = 'tex';
            app.kgkg_fuelLabel_7.Text = 'kg/kg_{fuel}';
            app.O_2Label_3 = uilabel(app.GridLayout6);
            app.O_2Label_3.Interpreter = 'tex';
            app.O_2Label_3.Text = 'O_2';
            app.kgkg_fuelLabel_8 = uilabel(app.GridLayout6);
            app.kgkg_fuelLabel_8.Interpreter = 'tex';
            app.kgkg_fuelLabel_8.Text = 'kg/kg_{fuel}';
            app.EditField_CB_CO2 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_CB_CO2.ValueDisplayFormat = '%.3f';
            app.EditField_CB_CO2.Editable = 'off';
            app.EditField_CB_H2O = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_CB_H2O.ValueDisplayFormat = '%.3f';
            app.EditField_CB_H2O.Editable = 'off';
            app.EditField_CB_N2 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_CB_N2.ValueDisplayFormat = '%.3f';
            app.EditField_CB_N2.Editable = 'off';
            app.EditField_CB_O2 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_CB_O2.ValueDisplayFormat = '%.3f';
            app.EditField_CB_O2.Editable = 'off';
            app.CombustionproductsvsExcessofairPanel = uipanel(app.GridLayout9);
            app.CombustionproductsvsExcessofairPanel.Title = 'Combustion products vs. Excess of air';
            app.UIAxes_CB = uiaxes(app.CombustionproductsvsExcessofairPanel);
            xlabel(app.UIAxes_CB, 'X')
            ylabel(app.UIAxes_CB, 'Y')
            zlabel(app.UIAxes_CB, 'Z')
            app.ExcessofAirSlider = uislider(app.CombustionproductsvsExcessofairPanel, 'range');
            app.ExcessofAirSlider.Limits = [0 500];
            app.ExcessofAirSlider.Value = [0 200];
            app.FlametemperatureTab = uitab(app.TabGroup);
            app.FlametemperatureTab.Title = 'Flame temperature';
            app.GridLayout8 = uigridlayout(app.FlametemperatureTab);
            app.EnergyanalisysPanel = uipanel(app.GridLayout8);
            app.EnergyanalisysPanel.Title = 'Energy analisys';
            app.GridLayout5_2 = uigridlayout(app.EnergyanalisysPanel);
            app.kJkmol_fuelLabel = uilabel(app.GridLayout5_2);
            app.kJkmol_fuelLabel.Interpreter = 'tex';
            app.kJkmol_fuelLabel.Text = 'kJ/kmol_{fuel}';
            app.Q_inLabel = uilabel(app.GridLayout5_2);
            app.Q_inLabel.Interpreter = 'tex';
            app.Q_inLabel.Text = 'Q_{in}';
            app.W_inLabel = uilabel(app.GridLayout5_2);
            app.W_inLabel.Interpreter = 'tex';
            app.W_inLabel.Text = 'W_{in}';
            app.Q_outLabel = uilabel(app.GridLayout5_2);
            app.Q_outLabel.Interpreter = 'tex';
            app.Q_outLabel.Text = 'Q_{out}';
            app.W_outLabel = uilabel(app.GridLayout5_2);
            app.W_outLabel.Interpreter = 'tex';
            app.W_outLabel.Text = 'W_{out}';
            app.EditFieldQin = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditFieldQin.Limits = [0 500000];
            app.EditFieldQin.ValueDisplayFormat = '%.0f';
            app.EditFieldWin = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditFieldWin.Limits = [0 500000];
            app.EditFieldWin.ValueDisplayFormat = '%.0f';
            app.EditFieldQout = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditFieldQout.Limits = [0 500000];
            app.EditFieldQout.ValueDisplayFormat = '%.0f';
            app.EditFieldWout = uieditfield(app.GridLayout5_2, 'numeric');
            app.EditFieldWout.Limits = [0 500000];
            app.EditFieldWout.ValueDisplayFormat = '%.0f';
            app.kJkmol_fuelLabel_2 = uilabel(app.GridLayout5_2);
            app.kJkmol_fuelLabel_2.Interpreter = 'tex';
            app.kJkmol_fuelLabel_2.Text = 'kJ/kmol_{fuel}';
            app.kJkmol_fuelLabel_3 = uilabel(app.GridLayout5_2);
            app.kJkmol_fuelLabel_3.Interpreter = 'tex';
            app.kJkmol_fuelLabel_3.Text = 'kJ/kmol_{fuel}';
            app.kJkmol_fuelLabel_4 = uilabel(app.GridLayout5_2);
            app.kJkmol_fuelLabel_4.Interpreter = 'tex';
            app.kJkmol_fuelLabel_4.Text = 'kJ/kmol_{fuel}';
            app.CombustionproductstemperaturePanel = uipanel(app.GridLayout8);
            app.CombustionproductstemperaturePanel.Title = 'Combustion products temperature';
            app.GridLayout10 = uigridlayout(app.CombustionproductstemperaturePanel);
            app.EditFieldT_p = uieditfield(app.GridLayout10, 'numeric');
            app.EditFieldT_p.ValueDisplayFormat = '%.2f';
            app.EditFieldT_p.Editable = 'off';
            app.CLabel_2 = uilabel(app.GridLayout10);
            app.CLabel_2.Text = 'ºC';
            app.T_pLabel = uilabel(app.GridLayout10);
            app.T_pLabel.Interpreter = 'tex';
            app.T_pLabel.Text = 'T_p';
            app.CombustionproductstemperaturevsExcessofairPanel = uipanel(app.GridLayout8);
            app.CombustionproductstemperaturevsExcessofairPanel.Title = 'Combustion products temperature vs. Excess of air';
            app.UIAxesT_p = uiaxes(app.CombustionproductstemperaturevsExcessofairPanel);
            xlabel(app.UIAxesT_p, 'X')
            ylabel(app.UIAxesT_p, 'Y')
            zlabel(app.UIAxesT_p, 'Z')
            app.SliderT_p = uislider(app.CombustionproductstemperaturevsExcessofairPanel, 'range');
            app.SliderT_p.Limits = [0 500];
            app.SliderT_p.Value = [0 500];
            app.OstwaldTriangleTab = uitab(app.TabGroup);
            app.OstwaldTriangleTab.Title = 'Ostwald Triangle';
            app.GridLayout11 = uigridlayout(app.OstwaldTriangleTab);
            app.CombustionproductsfromprobePanel = uipanel(app.GridLayout11);
            app.CombustionproductsfromprobePanel.Title = 'Combustion products from probe';
            app.GridLayout5_3 = uigridlayout(app.CombustionproductsfromprobePanel);
            app.vol_O_2Label = uilabel(app.GridLayout5_3);
            app.vol_O_2Label.Interpreter = 'tex';
            app.vol_O_2Label.Text = '%vol_{O_2}';
            app.EditFieldOstO2 = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditFieldOstO2.Limits = [0 500000];
            app.EditFieldOstO2.ValueDisplayFormat = '%.4f';
            app.EditFieldOstO2.AllowEmpty = 'on';
            app.EditFieldOstO2.Value = [];
            app.EditFieldOstCO = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditFieldOstCO.Limits = [0 500000];
            app.EditFieldOstCO.ValueDisplayFormat = '%.4f';
            app.EditFieldOstCO.AllowEmpty = 'on';
            app.EditFieldOstCO.Value = [];
            app.EditFieldOstCO2 = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditFieldOstCO2.Limits = [0 500000];
            app.EditFieldOstCO2.ValueDisplayFormat = '%.4f';
            app.EditFieldOstCO2.AllowEmpty = 'on';
            app.EditFieldOstCO2.Value = [];
            app.EditFieldOstE = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditFieldOstE.Limits = [0 500000];
            app.EditFieldOstE.ValueDisplayFormat = '%.4f';
            app.EditFieldOstE.AllowEmpty = 'on';
            app.EditFieldOstE.Value = [];
            app.EditFieldOstT = uieditfield(app.GridLayout5_3, 'numeric');
            app.EditFieldOstT.Limits = [0 500000];
            app.EditFieldOstT.ValueDisplayFormat = '%.2f';
            app.EditFieldOstT.AllowEmpty = 'on';
            app.EditFieldOstT.Value = [];
            app.CLabel_3 = uilabel(app.GridLayout5_3);
            app.CLabel_3.Text = 'ºC';
            app.vol_COLabel = uilabel(app.GridLayout5_3);
            app.vol_COLabel.Interpreter = 'tex';
            app.vol_COLabel.Text = '%vol_{CO}';
            app.vol_CO_2Label = uilabel(app.GridLayout5_3);
            app.vol_CO_2Label.Interpreter = 'tex';
            app.vol_CO_2Label.Text = '%vol_{CO_2}';
            app.ExcessofairLabel = uilabel(app.GridLayout5_3);
            app.ExcessofairLabel.Text = 'Excess of air';
            app.Label_9 = uilabel(app.GridLayout5_3);
            app.Label_9.Text = '%';
            app.TemperatureLabel = uilabel(app.GridLayout5_3);
            app.TemperatureLabel.Text = 'Temperature';
            app.PlotButton = uibutton(app.GridLayout5_3, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = '📈Plot';
            app.ClearButton = uibutton(app.GridLayout5_3, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.OstwaldTrianglePanel = uipanel(app.GridLayout11);
            app.OstwaldTrianglePanel.Title = 'Ostwald Triangle';
            app.UIAxesOstwald = uiaxes(app.OstwaldTrianglePanel);
            app.UIAxesOstwald.XTick = [];
            app.UIAxesOstwald.YTick = [];
            app.UIAxesOstwald.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flame_solver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end