classdef probe_dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ProbeMenu                  matlab.ui.container.Menu
        StartmeasurementMenu       matlab.ui.container.Menu
        ClearmeasurementsMenu      matlab.ui.container.Menu
        ClearlogMenu               matlab.ui.container.Menu
        CollecteddataPanel         matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        O_2EditField               matlab.ui.control.NumericEditField
        O_2EditFieldLabel          matlab.ui.control.Label
        COEditField                matlab.ui.control.NumericEditField
        COEditFieldLabel           matlab.ui.control.Label
        TemperatureEditField       matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel  matlab.ui.control.Label
        volLabel_2                 matlab.ui.control.Label
        volLabel                   matlab.ui.control.Label
        CLabel                     matlab.ui.control.Label
        MeasurementPanel           matlab.ui.container.Panel
        Lamp                       matlab.ui.control.Lamp
        LogTextArea                matlab.ui.control.TextArea
        LogTextAreaLabel           matlab.ui.control.Label
    end

    %mosquitto_sub -h broker.hivemq.com -t "esp32/sensors/#" -v
    %Code for cmd test
    methods (Access = private)

        function collectSensorData(app)
            try
                client = mqttclient("tcp://broker.hivemq.com");             % Creating the MQTT client and connecting to the broker.
                subscribe(client, "esp32/sensors/#");                       % Subscribe to topic "esp32/sensors/#".
                app.LogTextArea.Value = [app.LogTextArea.Value; ...
                    'Connecting...'];
                app.Lamp.Color = [1, 1, 0.5];                               % Switch the lamp to yellow.
            catch ME
                uialert(app.UIFigure, [ME.message], 'Connection error', ...
                    'Icon', ...                                             % If there is a connection error (server down) it shows an alert.
                    'error');
                app.Lamp.Color = [1, 0.502, 0.502];                         % Switch the lamp to light red.
                app.LogTextArea.Value = [app.LogTextArea.Value;...
                    'Error: Connection to MQTT broker was not established.'];
                return;
            end
            nowTime = datetime('now');                                              
            lastHeat = getappdata(0,'LastHeatTime');
            if isempty(lastHeat) || seconds(nowTime - lastHeat) >= 120
                app.LogTextArea.Value = [app.LogTextArea.Value; ...
                    'Heating sensors...'];
                setappdata(0,'LastHeatTime', nowTime);
                app.Lamp.Color = [1, 1, 0.5];
                pause(30);
            end
            temperature = strings(6,1);
            co_percent = strings(6,1);
            o2 = strings(6,1);
            dataReceived = false;
            for i = 1:4
                pause(2);
                data = read(client);
                disp(data)
                if ~isempty(data)
                    dataReceived = true;
                end
                for j = 1:height(data)
                    topic = string(data.Topic(j));
                    value = string(data.Data(j));
                    if topic == "esp32/sensors/temperature"
                        temperature(i) = value;
                        app.TemperatureEditField.Value = str2double(value);
                    elseif topic == "esp32/sensors/co"
                        co_percent(i) = value;
                        app.COEditField.Value = str2double(value);
                    elseif topic == "esp32/sensors/o2"
                        o2(i) = value;
                        app.O_2EditField.Value = str2double(value);
                    end
                end
            end

            if ~dataReceived
                uialert(app.UIFigure, ...
                    'Error: No data received. Possible causes: Internet disconnected or probe switched off.',  ...
                    'Error', 'Icon', 'error');
                app.Lamp.Color = [1 0.502 0.502];
                app.LogTextArea.Value = [app.LogTextArea.Value; ...
                    'Error: No data received.'];
            else
                app.LogTextArea.Value = [app.LogTextArea.Value; ...
                    'Data collection complete.'];
                clear client;
                app.Lamp.Color = [0.5, 1, 0.5];
                validT = temperature( temperature~="" & temperature~="NaN" );  % Filtragem de valores da variável temperature.
                if isempty(validT)
                    lastT = "";
                else
                    lastT = validT(end);
                end
                validCO = co_percent( co_percent~="" & co_percent~="NaN" );
                if isempty(validCO)
                    lastCO = "";
                else
                    lastCO = validCO(end);
                end
                validO2 = o2( o2~="" & o2~="NaN" );
                if isempty(validO2)
                    lastO2 = "";
                else
                    lastO2 = validO2(end);
                end
                setappdata(0, 'TemperatureData', lastT);
                setappdata(0, 'COData',          lastCO);
                setappdata(0, 'O2Data',          lastO2);

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Probe dashboard';
            app.UIFigure.Icon = 'icon_2.png';
            app.Lamp.Color = [1 0.5 0.5];
            app.LogTextArea.Value = '';
            app.TemperatureEditField.Value = [];
            app.COEditField.Value = [];
            app.O_2EditField.Value = [];
        end

        % Menu selected function: StartmeasurementMenu
        function StartmeasurementMenuSelected(app, event)
            app.LogTextArea.Value = '';
            app.TemperatureEditField.Value = [];
            app.COEditField.Value = [];
            app.O_2EditField.Value = [];
            app.Lamp.Color = [1 0.5 0.5];
            collectSensorData(app);

        end

        % Menu selected function: ClearmeasurementsMenu
        function ClearmeasurementsMenuSelected(app, event)
            app.TemperatureEditField.Value = [];
            app.COEditField.Value = [];
            app.O_2EditField.Value = [];
        end

        % Menu selected function: ClearlogMenu
        function ClearlogMenuSelected(app, event)
            app.LogTextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProbeMenu = uimenu(app.UIFigure);
            app.ProbeMenu.Text = 'Probe';
            app.StartmeasurementMenu = uimenu(app.ProbeMenu);
            app.StartmeasurementMenu.MenuSelectedFcn = createCallbackFcn(app, @StartmeasurementMenuSelected, true);
            app.StartmeasurementMenu.Text = 'Start measurement';
            app.ClearmeasurementsMenu = uimenu(app.ProbeMenu);
            app.ClearmeasurementsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearmeasurementsMenuSelected, true);
            app.ClearmeasurementsMenu.Text = 'Clear measurements';
            app.ClearlogMenu = uimenu(app.ProbeMenu);
            app.ClearlogMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearlogMenuSelected, true);
            app.ClearlogMenu.Text = 'Clear log';
            app.MeasurementPanel = uipanel(app.UIFigure);
            app.MeasurementPanel.Title = 'Measurement';
            app.LogTextAreaLabel = uilabel(app.MeasurementPanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.MeasurementPanel);
            app.LogTextArea.Editable = 'off';
            app.Lamp = uilamp(app.MeasurementPanel);
            app.CollecteddataPanel = uipanel(app.UIFigure);
            app.CollecteddataPanel.Title = 'Collected data';
            app.GridLayout2 = uigridlayout(app.CollecteddataPanel);
            app.CLabel = uilabel(app.GridLayout2);
            app.CLabel.Interpreter = 'tex';
            app.CLabel.Text = 'ºC';
            app.volLabel = uilabel(app.GridLayout2);
            app.volLabel.Interpreter = 'tex';
            app.volLabel.Text = '%vol.';
            app.volLabel_2 = uilabel(app.GridLayout2);
            app.volLabel_2.Interpreter = 'tex';
            app.volLabel_2.Text = '%vol.';
            app.TemperatureEditFieldLabel = uilabel(app.GridLayout2);
            app.TemperatureEditFieldLabel.Interpreter = 'tex';
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TemperatureEditField.Limits = [0 100];
            app.TemperatureEditField.ValueDisplayFormat = '%.5f';
            app.TemperatureEditField.AllowEmpty = 'on';
            app.TemperatureEditField.Editable = 'off';
            app.TemperatureEditField.Value = [];
            app.COEditFieldLabel = uilabel(app.GridLayout2);
            app.COEditFieldLabel.Interpreter = 'tex';
            app.COEditFieldLabel.Text = 'CO';
            app.COEditField = uieditfield(app.GridLayout2, 'numeric');
            app.COEditField.Limits = [0 100];
            app.COEditField.ValueDisplayFormat = '%.5f';
            app.COEditField.AllowEmpty = 'on';
            app.COEditField.Editable = 'off';
            app.COEditField.Value = [];
            app.O_2EditFieldLabel = uilabel(app.GridLayout2);
            app.O_2EditFieldLabel.Interpreter = 'tex';
            app.O_2EditFieldLabel.Text = 'O_2';
            app.O_2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.O_2EditField.Limits = [0 100];
            app.O_2EditField.ValueDisplayFormat = '%.5f';
            app.O_2EditField.AllowEmpty = 'on';
            app.O_2EditField.Editable = 'off';
            app.O_2EditField.Value = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = probe_dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end